classdef estopgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SystemResumeButton  matlab.ui.control.Button
        EStopReleaseButton  matlab.ui.control.Button
        EStopButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.EStopButton.Enable = 'off';
            app.EStopReleaseButton.Enable = 'on';
            app.SystemResumeButton.Enable = 'off';
            % Perform actions to stop robot motion (e.g., set joint velocities to zero)
            zeroVelocities = zeros(1, ur3.model.n);
            ur3.control(zeroVelocities);
            zeroVelocities = zeros(1, CRB15000.model.n);
            CRB15000.control(zeroVelocities);
        end

        % Button pushed function: EStopReleaseButton
        function EStopReleaseButtonPushed(app, event)
            app.EStopButton.Enable = 'off';
            app.EStopReleaseButton.Enable = 'off';
            app.SystemResumeButton.Enable = 'on';
            % return normal operation to robots, but dont start yet
        end

        % Button pushed function: SystemResumeButton
        function SystemResumeButtonPushed(app, event)
            app.EStopButton.Enable = 'on';
            app.EStopReleaseButton.Enable = 'off';
            app.SystemResumeButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E - Stop';
            app.EStopReleaseButton = uibutton(app.UIFigure, 'push');
            app.EStopReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @EStopReleaseButtonPushed, true);
            app.EStopReleaseButton.Text = 'E-Stop Release';
            app.SystemResumeButton = uibutton(app.UIFigure, 'push');
            app.SystemResumeButton.ButtonPushedFcn = createCallbackFcn(app, @SystemResumeButtonPushed, true);
            app.SystemResumeButton.Text = 'System Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estopgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end