classdef teachGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        q6Value        matlab.ui.control.NumericEditField
        q5Value        matlab.ui.control.NumericEditField
        q4Value        matlab.ui.control.NumericEditField
        q3Value        matlab.ui.control.NumericEditField
        q2Value        matlab.ui.control.NumericEditField
        q1Value        matlab.ui.control.NumericEditField
        ZSpinner       matlab.ui.control.Spinner
        ZSpinnerLabel  matlab.ui.control.Label
        YSpinner       matlab.ui.control.Spinner
        YSpinnerLabel  matlab.ui.control.Label
        XSpinner       matlab.ui.control.Spinner
        XSpinnerLabel  matlab.ui.control.Label
        q1Slider       matlab.ui.control.Slider
        q1SliderLabel  matlab.ui.control.Label
        q6Slider       matlab.ui.control.Slider
        q6SliderLabel  matlab.ui.control.Label
        q5Slider       matlab.ui.control.Slider
        q5SliderLabel  matlab.ui.control.Label
        q4Slider       matlab.ui.control.Slider
        q4SliderLabel  matlab.ui.control.Label
        q3Slider       matlab.ui.control.Slider
        q3SliderLabel  matlab.ui.control.Label
        q2Slider       matlab.ui.control.Slider
        q2SliderLabel  matlab.ui.control.Label
        plot           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelCRB % Where I will put the loaded puma
        modelUR3 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        qrCRB = [0	pi/2  -pi/2	0	0	0];
        qrUR3 = [pi/4	pi/2  -pi/2	0	0	0];
        qValue = [0, 0, 0, 0, 0, 0,];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.modelCRB = CRB15000; 
            app.modelCRB.model.base = transl([0,-0.25,0]);
            app.modelCRB.model.animate(app.qValue);
            hold on;         

%             app.modelUR3 = UR3;
%             app.modelUR3.model.base = transl([-0.5,0,0]);
%             app.modelUR3.model.animate(app.qrUR3);
%             drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            valueQ1 = app.q1Slider.Value;                                   % get slider value
            app.qValue(1,1) = deg2rad(valueQ1);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q1Value.Value = valueQ1;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            valueQ2 = app.q2Slider.Value;                                   % get slider value
            app.qValue(1,2) = deg2rad(valueQ2);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q2Value.Value = valueQ2;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            valueQ3 = app.q3Slider.Value;                                   % get slider value
            app.qValue(1,3) = deg2rad(valueQ3);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q3Value.Value = valueQ3;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            valueQ4 = app.q4Slider.Value;                                   % get slider value
            app.qValue(1,4) = deg2rad(valueQ4);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q4Value.Value = valueQ4;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            valueQ5 = app.q5Slider.Value;                                   % get slider value
            app.qValue(1,5) = deg2rad(valueQ5);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q5Value.Value = valueQ5;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            valueQ6 = app.q6Slider.Value;                                   % get slider value
            app.qValue(1,6) = deg2rad(valueQ6);                             % update stored qValue in rads
            app.modelCRB.model.animate(app.qValue);                         % update model
            app.q6Value.Value = valueQ6;                                    % update textbox
            
            % get XYZ and update spinner values
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));
            app.XSpinner.Value = XYZ(1,1);
            app.YSpinner.Value = XYZ(1,2);
            app.ZSpinner.Value = XYZ(1,3);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            valueX = app.XSpinner.Value;                                    % get spinner value
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));             % get old XYZ value from qValue
            XYZ(1,1) = valueX;                                              % update X
            q1 = app.modelCRB.model.ikcon(transl(XYZ));                     % get new q
            app.modelCRB.model.animate(q1);                                 % update model
            
            % store new qValue
            app.qValue = q1;
            
            % update slider values in degrees
            app.q1Slider.Value = rad2deg(q1(1,1));
            app.q2Slider.Value = rad2deg(q1(1,2));
            app.q3Slider.Value = rad2deg(q1(1,3));
            app.q4Slider.Value = rad2deg(q1(1,4));
            app.q5Slider.Value = rad2deg(q1(1,5));
            app.q6Slider.Value = rad2deg(q1(1,6));
            
            % update textbox values in degrees
            app.q1Value.Value = rad2deg(q1(1,1));
            app.q2Value.Value = rad2deg(q1(1,2));
            app.q3Value.Value = rad2deg(q1(1,3));
            app.q4Value.Value = rad2deg(q1(1,4));
            app.q5Value.Value = rad2deg(q1(1,5));
            app.q6Value.Value = rad2deg(q1(1,6));
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            valueY = app.XSpinner.Value;                                    % get spinner value
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));             % get old XYZ value from qValue
            XYZ(1,2) = valueY;                                              % update Y
            q1 = app.modelCRB.model.ikcon(transl(XYZ));                     % get new q
            app.modelCRB.model.animate(q1);                                 % update model
            
            % store new qValue
            app.qValue = q1;
            
            % update slider values in degrees
            app.q1Slider.Value = rad2deg(q1(1,1));
            app.q2Slider.Value = rad2deg(q1(1,2));
            app.q3Slider.Value = rad2deg(q1(1,3));
            app.q4Slider.Value = rad2deg(q1(1,4));
            app.q5Slider.Value = rad2deg(q1(1,5));
            app.q6Slider.Value = rad2deg(q1(1,6));

            % update textbox values in degrees
            app.q1Value.Value = rad2deg(q1(1,1));
            app.q2Value.Value = rad2deg(q1(1,2));
            app.q3Value.Value = rad2deg(q1(1,3));
            app.q4Value.Value = rad2deg(q1(1,4));
            app.q5Value.Value = rad2deg(q1(1,5));
            app.q6Value.Value = rad2deg(q1(1,6));
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            valueZ = app.XSpinner.Value;                                    % get spinner value
            XYZ = transl(app.modelCRB.model.fkine(app.qValue));             % get old XYZ value from qValue
            XYZ(1,3) = valueZ;                                              % update Z
            q1 = app.modelCRB.model.ikcon(transl(XYZ));                     % get new q
            app.modelCRB.model.animate(q1);                                 % update model
            
            % store new qValue
            app.qValue = q1;

            % update slider values in degrees
            app.q1Slider.Value = rad2deg(q1(1,1));
            app.q2Slider.Value = rad2deg(q1(1,2));
            app.q3Slider.Value = rad2deg(q1(1,3));
            app.q4Slider.Value = rad2deg(q1(1,4));
            app.q5Slider.Value = rad2deg(q1(1,5));
            app.q6Slider.Value = rad2deg(q1(1,6));

            % update textbox values in degrees
            app.q1Value.Value = rad2deg(q1(1,1));
            app.q2Value.Value = rad2deg(q1(1,2));
            app.q3Value.Value = rad2deg(q1(1,3));
            app.q4Value.Value = rad2deg(q1(1,4));
            app.q5Value.Value = rad2deg(q1(1,5));
            app.q6Value.Value = rad2deg(q1(1,6));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.q2SliderLabel = uilabel(app.figure1);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.figure1);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.MajorTicks = [];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.MinorTicks = [];
            app.q3SliderLabel = uilabel(app.figure1);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.figure1);
            app.q3Slider.Limits = [-225 85];
            app.q3Slider.MajorTicks = [];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.MinorTicks = [];
            app.q4SliderLabel = uilabel(app.figure1);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.figure1);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.MajorTicks = [];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.MinorTicks = [];
            app.q5SliderLabel = uilabel(app.figure1);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.figure1);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.MajorTicks = [];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.MinorTicks = [];
            app.q6SliderLabel = uilabel(app.figure1);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.figure1);
            app.q6Slider.Limits = [-270 270];
            app.q6Slider.MajorTicks = [];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6Slider.MinorTicks = [];
            app.q1SliderLabel = uilabel(app.figure1);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.figure1);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTicks = [];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.MinorTicks = [];
            app.XSpinnerLabel = uilabel(app.figure1);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.figure1);
            app.XSpinner.Step = 0.01;
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.figure1);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.figure1);
            app.YSpinner.Step = 0.01;
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.figure1);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.figure1);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.q1Value = uieditfield(app.figure1, 'numeric');
            app.q1Value.RoundFractionalValues = 'on';
            app.q2Value = uieditfield(app.figure1, 'numeric');
            app.q2Value.RoundFractionalValues = 'on';
            app.q3Value = uieditfield(app.figure1, 'numeric');
            app.q3Value.RoundFractionalValues = 'on';
            app.q4Value = uieditfield(app.figure1, 'numeric');
            app.q4Value.RoundFractionalValues = 'on';
            app.q5Value = uieditfield(app.figure1, 'numeric');
            app.q5Value.RoundFractionalValues = 'on';
            app.q6Value = uieditfield(app.figure1, 'numeric');
            app.q6Value.RoundFractionalValues = 'on';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teachGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end